classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AnalysisPanel            matlab.ui.container.Panel
        SamplesEditField         matlab.ui.control.NumericEditField
        SamplesEditFieldLabel    matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        umaxEditField            matlab.ui.control.NumericEditField
        umaxEditFieldLabel       matlab.ui.control.Label
        uminEditField            matlab.ui.control.NumericEditField
        uminEditFieldLabel       matlab.ui.control.Label
        VisualizationPanel       matlab.ui.container.Panel
        SamplingSpinner          matlab.ui.control.Spinner
        SamplingSpinnerLabel     matlab.ui.control.Label
        AutomaticlimitsCheckBox  matlab.ui.control.CheckBox
        yMaxLimit                matlab.ui.control.NumericEditField
        xMaxLimit                matlab.ui.control.NumericEditField
        yMinLimit                matlab.ui.control.NumericEditField
        yLimitsEditFieldLabel    matlab.ui.control.Label
        xMinLimit                matlab.ui.control.NumericEditField
        xLimitsEditFieldLabel    matlab.ui.control.Label
        ParameterPanel           matlab.ui.container.Panel
        uEditField               matlab.ui.control.NumericEditField
        uEditFieldLabel          matlab.ui.control.Label
        logSlider                matlab.ui.control.Slider
        logSliderLabel           matlab.ui.control.Label
        EquationsPanel           matlab.ui.container.Panel
        PresetDropDown           matlab.ui.control.DropDown
        PresetDropDownLabel      matlab.ui.control.Label
        Eq2Lamp                  matlab.ui.control.Lamp
        Eq1Lamp                  matlab.ui.control.Lamp
        yEquationField           matlab.ui.control.EditField
        fracdydtLabel            matlab.ui.control.Label
        xEquationField           matlab.ui.control.EditField
        dxdtLabel                matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Equation1
        Equation2

        anonFunction
    end
    
    methods (Access = private)
        
        function refreshGui(app)
            % Check equation validity
            eq1 = app.xEquationField.Value;
            eq2 = app.yEquationField.Value;
            
            areEquationsValid = true;
            try
                app.Equation1 = str2sym(eq1);
            catch ex
                disp(ex)
                app.Eq1Lamp.Color = [1, 0, 0];
                areEquationsValid = false;
            end

            try
                app.Equation2 = str2sym(eq2);
            catch ex
                disp(ex)
                app.Eq2Lamp.Color = [1, 0, 0];
                areEquationsValid = false;
            end
            if ~areEquationsValid
                return;
            end

            variables1 = symvar(app.Equation1);
            if prod(sum(string(variables1) == ["x"; "y"; "u"])) == 0
                app.Eq1Lamp.Color = [1, 0, 0];
                areEquationsValid = false;
            else
                app.Eq1Lamp.Color = [0, 1, 0];
            end

            variables2 = symvar(app.Equation2);
            if prod(sum(string(variables2) == ["x"; "y"; "u"])) == 0
                app.Eq2Lamp.Color = [1, 0, 0];
                areEquationsValid = false;
            else
                app.Eq2Lamp.Color = [0, 1, 0];
            end

            u_val = app.uEditField.Value;
            if ~areEquationsValid
                return
            end
            app.anonFunction = app.getAnonFunction(u_val);

            % Process equations

            equilibria = find_equilibria(app.Equation1, app.Equation2, u_val);
            cycles = {};
            cycle_x_min = inf;
            cycle_x_max = -inf;
            cycle_y_min = inf;
            cycle_y_max = -inf;
            for eq = equilibria
                if strcmp(eq.stability, "unstable")
                    cycle = app.findLimitCycle(eq, u_val);
                    if ~isempty(cycle)
                        cycles{end + 1} = cycle;
                        mins = min(cycle);
                        maxes = max(cycle);
                        cycle_x_min = min(cycle_x_min, mins(1));
                        cycle_x_max = max(cycle_x_max, maxes(1));
                        cycle_y_min = min(cycle_y_min, mins(2));
                        cycle_y_max = max(cycle_y_max, maxes(2));
                    end
                end
            end

            x_min = min(min([equilibria.x]), cycle_x_min);
            y_min = min(min([equilibria.y]), cycle_y_min);
            x_max = max(max([equilibria.x]), cycle_x_max);
            y_max = max(max([equilibria.y]), cycle_y_max);
            [x_min, x_max] = make_gap(x_min, x_max);
            [y_min, y_max] = make_gap(y_min, y_max);

            if app.AutomaticlimitsCheckBox.Value
                app.xMinLimit.Enable = "off";
                app.yMinLimit.Enable = "off";
                app.xMaxLimit.Enable = "off";
                app.yMaxLimit.Enable = "off";

                app.xMinLimit.Value = x_min;
                app.yMinLimit.Value = y_min;
                app.xMaxLimit.Value = x_max;
                app.yMaxLimit.Value = y_max;
            else
                app.xMinLimit.Enable = "on";
                app.yMinLimit.Enable = "on";
                app.xMaxLimit.Enable = "on";
                app.yMaxLimit.Enable = "on";
            end

            % Check graph limit validity
            if app.yMaxLimit.Value < app.yMinLimit.Value
                tmp = app.yMaxLimit.Value;
                app.yMaxLimit.Value = app.yMinLimit.Value;
                app.yMinLimit.Value = tmp;
            end
            if app.xMaxLimit.Value < app.xMinLimit.Value
                tmp = app.xMaxLimit.Value;
                app.xMaxLimit.Value = app.xMinLimit.Value;
                app.xMinLimit.Value = tmp;
            end

            if app.yMaxLimit.Value == app.yMinLimit.Value
                app.yMaxLimit.Value = app.yMaxLimit.Value ...
                    + max(1, abs(app.yMaxLimit.Value * 0.1));
            end
            if app.xMaxLimit.Value == app.xMinLimit.Value
                app.xMaxLimit.Value = app.xMaxLimit.Value ...
                    + max(1, abs(app.xMaxLimit.Value * 0.1));
            end
            
            [X, Y] = meshgrid(...
                linspace(app.xMinLimit.Value, app.xMaxLimit.Value, app.SamplingSpinner.Value), ...
                linspace(app.yMinLimit.Value, app.yMaxLimit.Value, app.SamplingSpinner.Value));
            U = X * 0;
            V = X * 0;
            
            for iter1 = 1:app.SamplingSpinner.Value
                for iter2 = 1:app.SamplingSpinner.Value
                    dirVec = app.anonFunction(0, [X(iter1, iter2); Y(iter1, iter2)]);
                    U(iter1, iter2) = dirVec(1);
                    V(iter1, iter2) = dirVec(2);
                end
            end

            quiver(app.UIAxes, X, Y, U, V);
            hold(app.UIAxes, "on");
            for eq = equilibria
                color = 'm';
                if startsWith(eq.stability, 'stable')
                    color = 'r';
                elseif startsWith(eq.stability, 'unstable')
                    color = 'b';
                elseif startsWith(eq.stability, 'saddle')
                    color = 'k';
                end

                mark = 'x';
                if startsWith(eq.rotation, "focus")
                    mark = 'o';
                elseif startsWith(eq.rotation, "node")
                    mark = '.';
                end
                scatter(app.UIAxes, eq.x, eq.y, [color, mark], 'SizeData',100);

                for cycleCell = cycles
                    plot(app.UIAxes, cycleCell{1}(:, 1), cycleCell{1}(:, 2), "Color", [0.7, 0.7, 0.7], "LineStyle","--");
                end
            end
            
            subEq1 = subs(app.Equation1, sym("u"), u_val);
            subEq2 = subs(app.Equation2, sym("u"), u_val);

            fimplicit(app.UIAxes, subEq1 * subEq2 == 0, ...
                [app.xMinLimit.Value, app.xMaxLimit.Value, app.yMinLimit.Value, app.yMaxLimit.Value]);
            hold(app.UIAxes, "off");

            app.UIAxes.XLim = [app.xMinLimit.Value, app.xMaxLimit.Value];
            app.UIAxes.YLim = [app.yMinLimit.Value, app.yMaxLimit.Value];
        end
        
        function cycle = findLimitCycle(app, equilibrium, u)
            cycle = [];
            if strcmp(equilibrium.stability, "unstable")
                %Try to find limit cycles
                if equilibrium.x ~= 0 || equilibrium.y ~= 0
                    startpoint = [equilibrium.x, equilibrium.y] * 1.01;
                else
                    startpoint = [0.001, 0.001];
                end
                cycle = check_for_limit_cycle(app.getAnonFunction(u), startpoint, 100);
            end
        end
        
        function anonFunction = getAnonFunction(app, u_val)
            eq1 = app.xEquationField.Value;
            eq2 = app.yEquationField.Value;

            eq1an = strrep(strrep(strrep(eq1, 'x', 'x(1)'), 'y', 'x(2)'), 'u', num2str(u_val));
            eq2an = strrep(strrep(strrep(eq2, 'x', 'x(1)'), 'y', 'x(2)'), 'u', num2str(u_val));
            
            anonFunction = str2func(['@(t, x)[',...
                                      eq1an, ...
                                      ';'...
                                      eq2an,...
                                      ']']);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.refreshGui();
        end

        % Value changed function: uEditField
        function uEditFieldValueChanged(app, event)
            value = app.uEditField.Value;
            if value <= 0
                value = 1;
                app.uEditField.Value = value;
            end
            if value < 10 ^ app.logSlider.Limits(1)
                value = 10 ^ app.logSlider.Limits(1);
                app.logSlider.Value = app.logSlider.Limits(1);
                app.uEditField.Value = value;
            elseif value > 10 ^ app.logSlider.Limits(2)
                value = 10 ^ app.logSlider.Limits(2);
                app.logSlider.Value = app.logSlider.Limits(2);
                app.uEditField.Value = value;
            else
                app.logSlider.Value = log10(value);
            end

            app.refreshGui();
        end

        % Value changed function: logSlider
        function logSliderValueChanged(app, event)
            value = app.logSlider.Value;
            app.uEditField.Value = 10 ^ value;

            app.refreshGui();
        end

        % Callback function
        function xMinLimitValueChanged(app, event)

        end

        % Value changed function: xMaxLimit, xMinLimit, yMaxLimit, 
        % ...and 1 other component
        function limitValueChanged(app, event)
            app.refreshGui();            
        end

        % Value changed function: xEquationField
        function equationChanged(app, event)
            app.refreshGui();
        end

        % Value changed function: AutomaticlimitsCheckBox, SamplingSpinner
        function uiChanged(app, event)
            app.refreshGui();
        end

        % Value changed function: uminEditField
        function uAnalysisValueChanged(app, event)
            umin = app.uminEditField.Value;
            umax = app.umaxEditField.Value;

            app.uminEditField.Value = min(umin, umax);
            app.umaxEditField.Value = max(umin, umax);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            f = figure;
            ax = axes(f);
            hold(ax, "on");
            for u = linspace(app.uminEditField.Value, app.umaxEditField.Value, app.SamplesEditField.Value)
                equilibria = find_equilibria(app.Equation1, app.Equation2, u);
                for eq = equilibria
                    color = 'm';
                    if startsWith(eq.stability, 'stable')
                        color = 'r';
                    elseif startsWith(eq.stability, 'unstable')
                        color = 'b';
                    elseif startsWith(eq.stability, 'saddle')
                        color = 'k';
                    end
    
                    mark = 'x';
                    if startsWith(eq.rotation, "focus")
                        mark = 'o';
                    elseif startsWith(eq.rotation, "node")
                        mark = '.';
                    end
                    scatter3(ax, u, eq.x, eq.y, [color, mark]);

                    cycle = app.findLimitCycle(eq, u);
                    if strcmp(eq.stability, "unstable") && ~isempty(cycle)
                        plot3(ax, u * ones(length(cycle), 1), cycle(:, 1), cycle(:, 2), "Color", 'k', "LineStyle","-");
                    end
                end
            end
            hold(ax, "on");
            xlabel(ax, "u");
            ylabel(ax, "x");
            zlabel(ax, "y");

            view(ax, 3);
        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            value = app.PresetDropDown.Value;
            switch value
                case "1"
                    app.xEquationField.Value = "x * (u - x^2 - y^2) - y";
                    app.yEquationField.Value = "y * (u - x^2 - y^2) - x";
                case "2"
                    app.xEquationField.Value = "y";
                    app.yEquationField.Value = "-x + u * (1 - x ^ 2) * y";
            end
            app.refreshGui();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.EquationsPanel = uipanel(app.UIFigure);
            app.EquationsPanel.Title = 'Equations';
            app.dxdtLabel = uilabel(app.EquationsPanel);
            app.dxdtLabel.Interpreter = 'latex';
            app.dxdtLabel.Text = '$$\frac{dx}{dt}=$$';
            app.xEquationField = uieditfield(app.EquationsPanel, 'text');
            app.xEquationField.ValueChangedFcn = createCallbackFcn(app, @equationChanged, true);
            app.xEquationField.Value = 'x * (u - x^2 - y^2) - y';
            app.fracdydtLabel = uilabel(app.EquationsPanel);
            app.fracdydtLabel.Interpreter = 'latex';
            app.fracdydtLabel.Text = '$$\frac{dy}{dt}=$$';
            app.yEquationField = uieditfield(app.EquationsPanel, 'text');
            app.yEquationField.Value = 'y * (u - x^2 - y^2) - x';
            app.Eq1Lamp = uilamp(app.EquationsPanel);
            app.Eq2Lamp = uilamp(app.EquationsPanel);
            app.PresetDropDownLabel = uilabel(app.EquationsPanel);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.EquationsPanel);
            app.PresetDropDown.Items = {'1', '2'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.PresetDropDown.Value = '1';
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.logSliderLabel = uilabel(app.ParameterPanel);
            app.logSliderLabel.Text = 'log';
            app.logSlider = uislider(app.ParameterPanel);
            app.logSlider.Limits = [-5 5];
            app.logSlider.ValueChangedFcn = createCallbackFcn(app, @logSliderValueChanged, true);
            app.uEditFieldLabel = uilabel(app.ParameterPanel);
            app.uEditFieldLabel.Text = 'u';
            app.uEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.uEditField.ValueChangedFcn = createCallbackFcn(app, @uEditFieldValueChanged, true);
            app.uEditField.Value = 1;
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.xLimitsEditFieldLabel = uilabel(app.VisualizationPanel);
            app.xLimitsEditFieldLabel.Text = 'x Limits';
            app.xMinLimit = uieditfield(app.VisualizationPanel, 'numeric');
            app.xMinLimit.ValueChangedFcn = createCallbackFcn(app, @limitValueChanged, true);
            app.yLimitsEditFieldLabel = uilabel(app.VisualizationPanel);
            app.yLimitsEditFieldLabel.Text = 'y Limits';
            app.yMinLimit = uieditfield(app.VisualizationPanel, 'numeric');
            app.yMinLimit.ValueChangedFcn = createCallbackFcn(app, @limitValueChanged, true);
            app.xMaxLimit = uieditfield(app.VisualizationPanel, 'numeric');
            app.xMaxLimit.ValueChangedFcn = createCallbackFcn(app, @limitValueChanged, true);
            app.xMaxLimit.Value = 10;
            app.yMaxLimit = uieditfield(app.VisualizationPanel, 'numeric');
            app.yMaxLimit.ValueChangedFcn = createCallbackFcn(app, @limitValueChanged, true);
            app.yMaxLimit.Value = 10;
            app.AutomaticlimitsCheckBox = uicheckbox(app.VisualizationPanel);
            app.AutomaticlimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @uiChanged, true);
            app.AutomaticlimitsCheckBox.Text = 'Automatic limits';
            app.AutomaticlimitsCheckBox.Value = true;
            app.SamplingSpinnerLabel = uilabel(app.VisualizationPanel);
            app.SamplingSpinnerLabel.Text = 'Sampling';
            app.SamplingSpinner = uispinner(app.VisualizationPanel);
            app.SamplingSpinner.Limits = [1 Inf];
            app.SamplingSpinner.RoundFractionalValues = 'on';
            app.SamplingSpinner.ValueChangedFcn = createCallbackFcn(app, @uiChanged, true);
            app.SamplingSpinner.Value = 30;
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.uminEditFieldLabel = uilabel(app.AnalysisPanel);
            app.uminEditFieldLabel.Text = 'u min';
            app.uminEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.uminEditField.ValueChangedFcn = createCallbackFcn(app, @uAnalysisValueChanged, true);
            app.uminEditField.Value = 0.1;
            app.umaxEditFieldLabel = uilabel(app.AnalysisPanel);
            app.umaxEditFieldLabel.Text = 'u max';
            app.umaxEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.umaxEditField.Value = 10;
            app.StartButton = uibutton(app.AnalysisPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SamplesEditFieldLabel = uilabel(app.AnalysisPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.SamplesEditField.Limits = [2 Inf];
            app.SamplesEditField.RoundFractionalValues = 'on';
            app.SamplesEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end